classdef Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CorpuzLlovitAudioEqualizerUIFigure  matlab.ui.Figure
        BrowseButton  matlab.ui.control.Button
        BrowseSong    matlab.ui.control.TextArea
        BG_Image      matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [file,location,indx] = uigetfile('*.mp3', 'Select a Sound Signal');
            if isequal(file,0)
               disp('User selected Cancel')
               app.BrowseSong.Value = sprintf('Browse a song to play');
            else
               disp(['User selected ', fullfile(location,file),... 
                     ' and filter index: ', num2str(indx)])
               app.BrowseSong.Value = sprintf('%s', file);
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CorpuzLlovitAudioEqualizerUIFigure = uifigure('Visible', 'off');
            app.CorpuzLlovitAudioEqualizerUIFigure.Name = 'Corpuz-Llovit Audio Equalizer';
            app.CorpuzLlovitAudioEqualizerUIFigure.Icon = fullfile(pathToMLAPP, 'audio-equalizer.512x506.png');
            app.BG_Image = uiimage(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.BG_Image.ImageSource = fullfile(pathToMLAPP, 'Fruitger_Aero_BG.png');
            app.BrowseSong = uitextarea(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.BrowseSong.Editable = 'off';
            app.BrowseSong.Tooltip = {'Displays the current song playing'};
            app.BrowseSong.Value = {'Browse a song to play'};
            app.BrowseButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = fullfile(pathToMLAPP, 'Aero Circle 08.png');
            app.BrowseButton.Tooltip = {'Browse songs in your current file'};
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.CorpuzLlovitAudioEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CorpuzLlovitAudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CorpuzLlovitAudioEqualizerUIFigure)
        end
    end
end